classdef entrega_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GraficarButton           matlab.ui.control.StateButton
        DistanciaEditField       matlab.ui.control.NumericEditField
        DistanciaEditFieldLabel  matlab.ui.control.Label
        cargasEditField          matlab.ui.control.NumericEditField
        cargasEditFieldLabel     matlab.ui.control.Label
        y2EditField              matlab.ui.control.NumericEditField
        y2EditFieldLabel         matlab.ui.control.Label
        x2EditField              matlab.ui.control.NumericEditField
        x2EditFieldLabel         matlab.ui.control.Label
        y1EditField              matlab.ui.control.NumericEditField
        y1EditFieldLabel         matlab.ui.control.Label
        x1EditField              matlab.ui.control.NumericEditField
        x1EditFieldLabel         matlab.ui.control.Label
        CargaEditField           matlab.ui.control.NumericEditField
        CargaLabel_2             matlab.ui.control.Label
        CargaEditField_2         matlab.ui.control.NumericEditField
        CargaLabel               matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GraficarButton
        function GraficarButtonValueChanged(app, event)
            cla(app.UIAxes);
            Qp = app.CargaEditField.Value; %Carga positiva
            Qn = -abs(app.CargaEditField_2.Value); %Carga negativa
            d = app.DistanciaEditField.Value; %Distancia entre las filas
            n = app.cargasEditField.Value; %Numero de cargas
            kC = 9e9; %Constante de Coulomb
            sep_x = 0.3; %Separacion entre cargas

            ap = abs(Qp)/100; % Tamaño de las partículas de la primera fila (proporcional a la carga)
            an = abs(Qn)/100; % Tamaño de las partículas de la segunda fila (proporcional a la carga)

            %Grid
            yliminf = -d/2-0.5;
            ylimsup = d/2+0.5;
            axis = app.UIAxes;
            axis.XLim = [-1 1];
            axis.YLim = [yliminf ylimsup];
            XLIM = app.UIAxes.XLim;
            YLIM = app.UIAxes.YLim;
            x = XLIM(1):0.05:XLIM(2);
            y = YLIM(1):0.05:YLIM(2);
            [xG,yG] = meshgrid(x,y);

            % Inicializar los campos eléctricos
            Ex = zeros(size(xG));
            Ey = zeros(size(yG));
            
            %Colores de las cargas
            colorP = [1 0 0];
            colorN = [0 0 1];
    

    hold(app.UIAxes,"on");

    % Generar posiciones para la primera fila (superior)
    yCp = d/2;
    for i = 1:n
        % Calcular posición horizontal centrada
        xCp = (-(n-1)/2 + (i-1)) * sep_x;
        
        % Cálculo del campo eléctrico para esta carga usando ciclos for
        for row = 1:size(xG, 1)
            for col = 1:size(xG, 2)
                Rx_p = xG(row, col) - xCp;
                Ry_p = yG(row, col) - yCp;
                R_p = (Rx_p^2 + Ry_p^2);
                if R_p > 0
                    R_p = R_p^(3/2);
                    Ex(row, col) = Ex(row, col) + kC * Qp * Rx_p / R_p;
                    Ey(row, col) = Ey(row, col) + kC * Qp * Ry_p / R_p;
                end
            end
        end
        
        % Dibujar partícula de la primera fila con tamaño proporcional a la carga
        h_p = rectangle(app.UIAxes,'Position', [xCp - ap/2, yCp - ap/2, ap, ap], 'Curvature', [1 1]);
        set(h_p, 'FaceColor', colorP, 'EdgeColor', [0 0 0]);
    end

    % Generar posiciones para la segunda fila (inferior)
    yCn = -d/2;
    for i = 1:n
        % Calcular posición horizontal centrada
        xCn = (-(n-1)/2 + (i-1)) * sep_x;
        
        % Cálculo del campo eléctrico para esta carga usando ciclos for
        for row = 1:size(xG, 1)
            for col = 1:size(xG, 2)
                Rx_n = xG(row, col) - xCn;
                Ry_n = yG(row, col) - yCn;
                R_n = (Rx_n^2 + Ry_n^2);
                if R_n > 0
                    R_n = R_n^(3/2);
                    Ex(row, col) = Ex(row, col) + kC * Qn * Rx_n / R_n;
                    Ey(row, col) = Ey(row, col) + kC * Qn * Ry_n / R_n;
                end
            end
        end
        
        % Dibujar partícula de la segunda fila con tamaño proporcional a la carga
        h_n = rectangle(app.UIAxes, 'Position', [xCn - an/2, yCn - an/2, an, an], 'Curvature', [1 1]);
        set(h_n, 'FaceColor', colorN, 'EdgeColor', [0 0 0]);
    end

    % Calcular magnitud del campo eléctrico y vectores unitarios
    E = sqrt(Ex.^2 + Ey.^2);
    u = zeros(size(E));
    v = zeros(size(E));
    
    % Calcular vectores unitarios usando ciclos for
    for row = 1:size(E, 1)
        for col = 1:size(E, 2)
            if E(row, col) > 0
                u(row, col) = Ex(row, col) / E(row, col);
                v(row, col) = Ey(row, col) / E(row, col);
            end
        end
    end
    
    % Graficar el campo eléctrico con vectores
    h = quiver(app.UIAxes, xG, yG, u, v, 'autoscalefactor', 0.5);
    set(h, 'color', [0.7 0 0.7]);
    
    %Definicion de los puntos especificados
    x1 = app.x1EditField.Value;
    y1 = app.y1EditField.Value;
    x2 = app.x2EditField.Value;
    y2 = app.y2EditField.Value;
    
    %Obtención de los indices (mas cercans al valor deseado) del array
    i1 = round((x1+1)/0.05)+1;
    j1 = round((y1-yliminf)/0.05)+1;
    i2 = round((x2+1)/0.05)+1;
    j2 = round((y2-yliminf)/0.05)+1;

    %Calculo de la magnitud del campo electrico
    e1 = round(E(i1,j1),2, "significant");
    e2 = round(E(i2,j2),2, "significant");
    
    plot(app.UIAxes, x1,y1,".",x2,y2,".")
    
    e1str = strcat("E =",num2str(e1));
    e2str = strcat("E =",num2str(e2));
    text(app.UIAxes,x1,y1,e1str);
    text(app.UIAxes,x2,y2,e2str);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Campo Eléctrico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-5 5];
            app.UIAxes.YLim = [-5 5];
            app.CargaLabel = uilabel(app.UIFigure);
            app.CargaLabel.Text = 'Carga (-)';
            app.CargaEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CargaEditField_2.Value = 1;
            app.CargaLabel_2 = uilabel(app.UIFigure);
            app.CargaLabel_2.Text = 'Carga (+)';
            app.CargaEditField = uieditfield(app.UIFigure, 'numeric');
            app.CargaEditField.Value = 1;
            app.x1EditFieldLabel = uilabel(app.UIFigure);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.UIFigure, 'numeric');
            app.x1EditField.Value = 0.5;
            app.y1EditFieldLabel = uilabel(app.UIFigure);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.UIFigure, 'numeric');
            app.x2EditFieldLabel = uilabel(app.UIFigure);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.UIFigure, 'numeric');
            app.x2EditField.Value = -0.5;
            app.y2EditFieldLabel = uilabel(app.UIFigure);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.UIFigure, 'numeric');
            app.cargasEditFieldLabel = uilabel(app.UIFigure);
            app.cargasEditFieldLabel.Text = '# cargas';
            app.cargasEditField = uieditfield(app.UIFigure, 'numeric');
            app.cargasEditField.Value = 5;
            app.DistanciaEditFieldLabel = uilabel(app.UIFigure);
            app.DistanciaEditFieldLabel.Text = 'Distancia';
            app.DistanciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanciaEditField.Value = 1;
            app.GraficarButton = uibutton(app.UIFigure, 'state');
            app.GraficarButton.ValueChangedFcn = createCallbackFcn(app, @GraficarButtonValueChanged, true);
            app.GraficarButton.Text = 'Graficar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = entrega_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end