classdef interfazss < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EscalaSlider                 matlab.ui.control.Slider
        EscalaSliderLabel            matlab.ui.control.Label
        PermitividadEditField        matlab.ui.control.NumericEditField
        PermitividadEditFieldLabel   matlab.ui.control.Label
        EjecutarButton               matlab.ui.control.Button
        AzimutSlider                 matlab.ui.control.Slider
        AzimutSliderLabel            matlab.ui.control.Label
        ElevacinSlider               matlab.ui.control.Slider
        ElevacinSliderLabel          matlab.ui.control.Label
        ContornoCheckBox             matlab.ui.control.CheckBox
        DistanciaEditField           matlab.ui.control.NumericEditField
        DistanciaEditFieldLabel      matlab.ui.control.Label
        Campodecarga2EditField       matlab.ui.control.NumericEditField
        Campodecarga2EditFieldLabel  matlab.ui.control.Label
        Campodecarga1EditField       matlab.ui.control.NumericEditField
        Campodecarga1EditFieldLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EjecutarButton
        function EjecutarButtonPushed(app, event)
function SimularButtonPushed(app, event)
    % Obtener valores de los controles
    Qp = app.QpField.Value;
    Qn = app.QnField.Value;
    d = app.DistanciaField.Value;
    er = app.PermitividadField.Value;
    mostrarContorno = app.ContornoCheckBox.Value;
    escala = app.EscalaSlider.Value;
    az = app.AzimuthSlider.Value;
    el = app.ElevationSlider.Value;

    kC = 9e9 / er;  % Constante de Coulomb ajustada

    lim = 6;
    N = 30;
    [x, y] = meshgrid(linspace(-lim, lim, N), linspace(-lim, lim, N));
    Ex = zeros(size(x)); Ey = zeros(size(x));

    % Cargas y posiciones
    cargas = [Qp, Qn];
    posiciones = [0, -d/2; 0, d/2];

    for i = 1:2
        xC = posiciones(i,1); yC = posiciones(i,2); q = cargas(i);
        for row = 1:N
            for col = 1:N
                Rx = x(row,col) - xC;
                Ry = y(row,col) - yC;
                R2 = Rx^2 + Ry^2;
                if R2 > 0
                    R4 = R2^2;
                    Ex(row,col) = Ex(row,col) + kC*q*Rx/R4;
                    Ey(row,col) = Ey(row,col) + kC*q*Ry/R4;
                end
            end
        end
    end

    % Magnitud y dirección
    E = sqrt(Ex.^2 + Ey.^2);
    u = Ex ./ E; u(E==0) = 0;
    v = Ey ./ E; v(E==0) = 0;
end

        end

        % Value changed function: AzimutSlider
        function AzimutSliderValueChanged(app, event)
            value = app.AzimutSlider.Value;
            
        end

        % Value changed function: ElevacinSlider
        function ElevacinSliderValueChanged(app, event)
            value = app.ElevacinSlider.Value;
            
        end

        % Value changed function: ContornoCheckBox
        function ContornoCheckBoxValueChanged(app, event)
            value = app.ContornoCheckBox.Value;
            
        end

        % Value changed function: DistanciaEditField
        function DistanciaEditFieldValueChanged(app, event)
            value = app.DistanciaEditField.Value;
            
        end

        % Value changed function: Campodecarga2EditField
        function Campodecarga2EditFieldValueChanged(app, event)
            value = app.Campodecarga2EditField.Value;
            
        end

        % Value changed function: Campodecarga1EditField
        function Campodecarga1EditFieldValueChanged(app, event)
            value = app.Campodecarga1EditField.Value;
            
        end

        % Value changed function: PermitividadEditField
        function PermitividadEditFieldValueChanged(app, event)
            value = app.PermitividadEditField.Value;
            
        end

        % Value changed function: EscalaSlider
        function EscalaSliderValueChanged(app, event)
            value = app.EscalaSlider.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % Graficar en 2D
    cla(app.Campo2D);
    quiver(app.Campo2D, x, y, u, v, escala, 'Color', [0.5 0 0.5]); hold(app.Campo2D, 'on');
    if mostrarContorno
        contour(app.Campo2D, x, y, E, 15);
    end
    plot(app.Campo2D, 0, -d/2, 'ro', 'MarkerFaceColor', 'r');
    plot(app.Campo2D, 0, d/2, 'bo', 'MarkerFaceColor', 'b');
    title(app.Campo2D, 'Campo Eléctrico (2D)');
    axis(app.Campo2D, [-lim lim -lim lim]); hold(app.Campo2D, 'off');

    % Gráfico 3D de intensidad
    cla(app.Campo3D);
    surf(app.Campo3D, x, y, E, 'EdgeColor', 'none');
    view(app.Campo3D, az, el);
    colormap(app.Campo3D, 'jet'); colorbar(app.Campo3D);
    title(app.Campo3D, 'Intensidad del Campo (3D)');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Campodecarga1EditFieldLabel = uilabel(app.UIFigure);
            app.Campodecarga1EditFieldLabel.Text = 'Campo de carga 1';
            app.Campodecarga1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Campodecarga1EditField.ValueChangedFcn = createCallbackFcn(app, @Campodecarga1EditFieldValueChanged, true);
            app.Campodecarga2EditFieldLabel = uilabel(app.UIFigure);
            app.Campodecarga2EditFieldLabel.Text = 'Campo de carga 2';
            app.Campodecarga2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Campodecarga2EditField.ValueChangedFcn = createCallbackFcn(app, @Campodecarga2EditFieldValueChanged, true);
            app.DistanciaEditFieldLabel = uilabel(app.UIFigure);
            app.DistanciaEditFieldLabel.Text = 'Distancia';
            app.DistanciaEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanciaEditField.ValueChangedFcn = createCallbackFcn(app, @DistanciaEditFieldValueChanged, true);
            app.ContornoCheckBox = uicheckbox(app.UIFigure);
            app.ContornoCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContornoCheckBoxValueChanged, true);
            app.ContornoCheckBox.Text = 'Contorno';
            app.ElevacinSliderLabel = uilabel(app.UIFigure);
            app.ElevacinSliderLabel.Text = 'Elevación';
            app.ElevacinSlider = uislider(app.UIFigure);
            app.ElevacinSlider.ValueChangedFcn = createCallbackFcn(app, @ElevacinSliderValueChanged, true);
            app.AzimutSliderLabel = uilabel(app.UIFigure);
            app.AzimutSliderLabel.Text = 'Azimut';
            app.AzimutSlider = uislider(app.UIFigure);
            app.AzimutSlider.ValueChangedFcn = createCallbackFcn(app, @AzimutSliderValueChanged, true);
            app.EjecutarButton = uibutton(app.UIFigure, 'push');
            app.EjecutarButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarButtonPushed, true);
            app.EjecutarButton.Text = 'Ejecutar';
            app.PermitividadEditFieldLabel = uilabel(app.UIFigure);
            app.PermitividadEditFieldLabel.Text = 'Permitividad';
            app.PermitividadEditField = uieditfield(app.UIFigure, 'numeric');
            app.PermitividadEditField.ValueChangedFcn = createCallbackFcn(app, @PermitividadEditFieldValueChanged, true);
            app.EscalaSliderLabel = uilabel(app.UIFigure);
            app.EscalaSliderLabel.Text = 'Escala';
            app.EscalaSlider = uislider(app.UIFigure);
            app.EscalaSlider.ValueChangedFcn = createCallbackFcn(app, @EscalaSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfazss

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end